classdef EditSourceDialogue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SourceRecordTable         matlab.ui.control.Table
        FillinasourcerecordLabel  matlab.ui.control.Label
        InsertrecordButton        matlab.ui.control.Button
        MessageTextAreaLabel      matlab.ui.control.Label
        MessageTextArea           matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp  % main app object
        query
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.query = lab.Source;
            app.SourceRecordTable.ColumnName = app.query.header.names;
            app.SourceRecordTable.ColumnEditable = true(size(app.query.header.names));
            app.SourceRecordTable.Data = {'', '', '','','',''};
            
        end

        % Cell edit callback: SourceRecordTable
        function SourceRecordTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: InsertrecordButton
        function InsertrecordButtonPushed(app, event)
            for i = 1:length(app.SourceRecordTable.ColumnName)
                name = app.SourceRecordTable.ColumnName{i};
                value = app.SourceRecordTable.Data{i};
                if ~isempty(value)
                    entry.(name) = value;
                end
            end
            try
                insert(lab.Source, entry)
                app.MessageTextArea.Value = 'Success!';
                app.SourceRecordTable.Data = {'', '', ''};
                app.CallingApp.loadTable(lab.Source)
            catch e
                app.MessageTextArea.Value = ['The following error happens during insertion, please edit record: ', e.message];
            end
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            app.CallingApp.AddSourceButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.SourceRecordTable = uitable(app.UIFigure);
            app.SourceRecordTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SourceRecordTable.RowName = {};
            app.SourceRecordTable.CellEditCallback = createCallbackFcn(app, @SourceRecordTableCellEdit, true);
            app.FillinasourcerecordLabel = uilabel(app.UIFigure);
            app.FillinasourcerecordLabel.Text = 'Fill in a source record';
            app.InsertrecordButton = uibutton(app.UIFigure, 'push');
            app.InsertrecordButton.ButtonPushedFcn = createCallbackFcn(app, @InsertrecordButtonPushed, true);
            app.InsertrecordButton.Text = 'Insert record';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditSourceDialogue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end