classdef MouseGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StrainTable                 matlab.ui.control.Table
        StrainLabel                 matlab.ui.control.Label
        SubjectfiltersLabel         matlab.ui.control.Label
        UpdateSubjectButton         matlab.ui.control.Button
        SubmitfilterButton          matlab.ui.control.Button
        ResetfilterButton           matlab.ui.control.Button
        DeleteSubjectButton         matlab.ui.control.Button
        bornbeforeDatePickerLabel   matlab.ui.control.Label
        bornbeforeFilterDatePicker  matlab.ui.control.DatePicker
        bornafterDatePickerLabel    matlab.ui.control.Label
        bornafterFilterDatePicker   matlab.ui.control.DatePicker
        strainDropDownLabel         matlab.ui.control.Label
        strainFilterDropDown        matlab.ui.control.DropDown
        MessageTextAreaLabel        matlab.ui.control.Label
        OtherMessageTextArea        matlab.ui.control.TextArea
        SubjectTable                matlab.ui.control.Table
        ZygosityTable               matlab.ui.control.Table
        AddSubjectButton            matlab.ui.control.Button
        AlleleTable                 matlab.ui.control.Table
        AlleleLabel                 matlab.ui.control.Label
        SourceTable                 matlab.ui.control.Table
        SourceLabel                 matlab.ui.control.Label
        AddSourceButton             matlab.ui.control.Button
        DeleteSourceButton          matlab.ui.control.Button
        UpdateSourceButton          matlab.ui.control.Button
        AddStrainButton             matlab.ui.control.Button
        DeleteStrainButton          matlab.ui.control.Button
        UpdateStrainButton          matlab.ui.control.Button
        AddAlleleButton             matlab.ui.control.Button
        DeleteAlleleButton          matlab.ui.control.Button
        UpdateAlleleButton          matlab.ui.control.Button
        DeathTable                  matlab.ui.control.Table
        ZygosityLabel               matlab.ui.control.Label
        DeathLabel                  matlab.ui.control.Label
        NicknameTable               matlab.ui.control.Table
        NicknameLabel               matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        StrainAlleleLabel           matlab.ui.control.Label
        ClearselectionButton        matlab.ui.control.Button
        subject_idEditFieldLabel    matlab.ui.control.Label
        subject_idEditField         matlab.ui.control.EditField
        nicknameEditFieldLabel      matlab.ui.control.Label
        nicknameEditField           matlab.ui.control.EditField
        SubjectLabel_2              matlab.ui.control.Label
    end

    
    properties (Access = private)
      
        % other apps to be called
        AddSourceApp
        UpdateSourceApp
        AddStrainApp    % Add strain window
        UpdateStrainApp
        AddAlleleApp
        UpdateAlleleApp
        AddSubjectApp
        UpdateSubjectApp
        DeleteSubjectApp
        
    end
    
    properties (Access = public)
        
        conn
        mainTable = subject.Subject
         % current data shown in the form
        sourceData      % current data for table lab.Source
        strainData % current data for table subject.Strain
        alleleData 
        subjectData
        nicknameData
        zygosityData
        deathData
        
        % tables used
        sourceColumnNames
        strainColumnNames
        alleleColumnNames
        subjectColumnNames
        nicknameColumnNames
        zygosityColumnNames
        deathColumnNames
        
        % selected key of each table
        selectedStrainKey = struct('strain_name', 'dummy')
        selectedSubjectKey = struct('subject_id', 'dummy')
        selectedSubjectIndex
        
        % current filters
        bornBeforeFilter = struct()
        bornAfterFilter = sprintf('dob > "%s"', datestr(datetime('now')-calmonths(6), 'yyyy-mm-dd HH:MM:ss'))
        subject_idFilter = struct()
        nicknameFilter = subject.Nickname
        strainFilter = struct('strain_name', 'jRGECO')
        currentSubjectQuery
    end
    
    methods (Access = public)
        
        function loadTable(app, table, sub)
            
            if nargin < 3
                sub = false;
            end
            tableName = extractAfter(class(table), '.');
            dataName = [lower(tableName) 'Data'];
            listTableName = [tableName 'Table'];
            if sub
                if ismember(tableName, {'Nickname', 'Zygosity', 'Death'})
                    currentData = fetch(table & app.selectedSubjectKey, '*');
                    
                    app.(dataName) = currentData;
                elseif strcmp(tableName, 'StrainAllele')
                    currentData = fetch(table & app.selectedStrainKey, '*');
                    app.(dataName) = currentData;
                end
            else
                currentData = fetch(table, '*');
                app.(dataName) = currentData;
                app.(['Update' tableName 'Button']).Enable = 'off';
                app.(['Delete' tableName 'Button']).Enable = 'off';
            end
            columnNames = fieldnames(currentData);
            app.(listTableName).ColumnName = columnNames;
            app.([lower(tableName) 'ColumnNames']) = columnNames;
            
            
            if ~isempty(currentData)
                app.(listTableName).Data = struct2cell(currentData)';
            else
                app.(listTableName).Data = [];
            end
            
        end
        
        function initializeFilters(app)
            strains = fetchn(subject.Strain & subject.Subject, 'strain_name');
            app.strainFilterDropDown.Items = strains;
            app.strainFilterDropDown.Value = 'jRGECO';
            
            app.bornafterFilterDatePicker.Value = datetime('now') - calmonths(6);
        end
        
        
        function applyFilters(app)
            filters = {app.bornAfterFilter, app.bornBeforeFilter, app.subject_idFilter, ... 
                app.strainFilter, app.nicknameFilter};
            
            app.currentSubjectQuery = subject.Subject & filters;
            
        end
        
        function clearSelection(app)
            app.selectedSubjectKey = struct('subject_id', 'dummy');
            app.loadTable(subject.Nickname, true)
            app.loadTable(subject.Death, true)
            app.loadTable(subject.Zygosity, true)
            app.DeleteSubjectButton.Enable = 'off';
            app.UpdateSubjectButton.Enable = 'off';
            app.ClearselectionButton.Enable = 'off';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.conn = dj.conn();
            app.initializeFilters()
            app.applyFilters()
            app.loadTable(app.currentSubjectQuery)
            app.loadTable(lab.Source)
            app.loadTable(subject.Strain)
            app.loadTable(subject.Allele)
            app.loadTable(subject.Nickname, true)
            app.loadTable(subject.Zygosity, true)
            app.loadTable(subject.Death, true)
            app.ClearselectionButton.Enable = 'off';
            
        end

        % Button pushed function: AddStrainButton
        function AddStrainButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AddStrainButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AddStrainApp = EditStrainDialogue(app);
        end

        % Button pushed function: AddSourceButton
        function AddSourceButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AddSourceButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AddSourceApp = EditSourceDialogue(app);
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AddSubjectButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AddSubjectApp = EditSubjectDialogue(app, 'add');
        end

        % Cell selection callback: SubjectTable
        function SubjectTableCellSelection(app, event)
            indices = event.Indices;
            app.selectedSubjectIndex = indices(1);
            app.selectedSubjectKey = struct('subject_id', app.SubjectTable.Data(indices(1), 1));
            app.loadTable(subject.Nickname, true)
            app.loadTable(subject.Death, true)
            app.loadTable(subject.Zygosity, true)
            app.DeleteSubjectButton.Enable = 'on';
            app.UpdateSubjectButton.Enable = 'on';
            app.ClearselectionButton.Enable = 'on';
            
        end

        % Button pushed function: ClearselectionButton
        function ClearselectionButtonPushed(app, event)
           app.clearSelection()
        end

        % Button pushed function: DeleteSubjectButton
        function DeleteSubjectButtonPushed(app, event)
            app.DeleteSubjectButton.Enable = 'off';
            app.DeleteSubjectApp = DeletionConfirm(app, 'Subject');
        end

        % Button pushed function: UpdateSubjectButton
        function UpdateSubjectButtonPushed(app, event)
            % Disable the button while dialog is open
            app.UpdateSubjectButton.Enable = 'off';
            app.DeleteSubjectButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.UpdateSubjectApp = EditSubjectDialogue(app, 'update');
        end

        % Value changed function: subject_idEditField
        function subject_idEditFieldValueChanged(app, event)
            value = app.subject_idEditField.Value;
            app.subject_idFilter = ['subject_id like "%' value '%"'];
        end

        % Value changed function: nicknameEditField
        function nicknameEditFieldValueChanged(app, event)
            value = app.nicknameEditField.Value;
            if ~isempty(value)
                app.nicknameFilter = subject.Nickname & ['nickname like "%', value, '%"'];
            else
                app.nicknameFilter = struct();
            end
            
        end

        % Value changed function: strainFilterDropDown
        function strainFilterDropDownValueChanged(app, event)
            value = app.strainFilterDropDown.Value;
            app.strainFilter = struct('strain_name', value);
        end

        % Value changed function: bornbeforeFilterDatePicker
        function bornbeforeFilterDatePickerValueChanged(app, event)
            value = app.bornbeforeFilterDatePicker.Value;
            app.bornBeforeFilter = sprintf('dob < "%s"', datestr(value, 'YYYY-mm-dd'));
        end

        % Value changed function: bornafterFilterDatePicker
        function bornafterFilterDatePickerValueChanged(app, event)
            value = app.bornafterFilterDatePicker.Value;
            app.bornAfterFilter = sprintf('dob > "%s"', datestr(value, 'YYYY-mm-dd'));
        end

        % Button pushed function: SubmitfilterButton
        function SubmitfilterButtonPushed(app, event)
            app.applyFilters()
            app.loadTable(app.currentSubjectQuery)
            % if there is data in the subject table, select the first entry
            % and load the additional tables
            if ~isempty(app.SubjectTable.Data)
                app.selectedSubjectKey = struct('subject_id', app.SubjectTable.Data{1, 1});
                app.loadTable(subject.Nickname, true)
                app.loadTable(subject.Zygosity, true)
                app.loadTable(subject.Death, true)
            % otherwise, clear the selected subject key
            else
                app.clearSelection()
            end
        end

        % Button pushed function: ResetfilterButton
        function ResetfilterButtonPushed(app, event)
            app.bornBeforeFilter = struct();
            app.bornAfterFilter = sprintf('dob > "%s"', datestr(datetime('now')-calmonths(6), 'yyyy-mm-dd HH:MM:ss'));
            app.subject_idFilter = struct();
            app.nicknameFilter = subject.Nickname;
            app.strainFilter = struct('strain_name', 'jRGECO');
            app.bornbeforeFilterDatePicker.Value = NaT;
            app.bornafterFilterDatePicker.Value = datetime('now')-calmonths(6);
            app.subject_idEditField.Value = '';
            app.nicknameEditField.Value = '';
            app.strainFilterDropDown.Value = 'jRGECO';
            app.applyFilters()
            app.loadTable(app.currentSubjectQuery);
            app.clearSelection()
        end

        % Button pushed function: AddAlleleButton
        function AddAlleleButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AddAlleleButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AddAlleleApp = EditAlleleDialogue(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StrainTable = uitable(app.UIFigure);
            app.StrainTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StrainTable.RowName = {};
            app.StrainLabel = uilabel(app.UIFigure);
            app.StrainLabel.Text = 'Strain';
            app.SubjectfiltersLabel = uilabel(app.UIFigure);
            app.SubjectfiltersLabel.Text = 'Subject filters';
            app.UpdateSubjectButton = uibutton(app.UIFigure, 'push');
            app.UpdateSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSubjectButtonPushed, true);
            app.UpdateSubjectButton.Text = 'Update the mouse record';
            app.SubmitfilterButton = uibutton(app.UIFigure, 'push');
            app.SubmitfilterButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitfilterButtonPushed, true);
            app.SubmitfilterButton.Text = 'Submit filter';
            app.ResetfilterButton = uibutton(app.UIFigure, 'push');
            app.ResetfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ResetfilterButtonPushed, true);
            app.ResetfilterButton.Text = 'Reset filter';
            app.DeleteSubjectButton = uibutton(app.UIFigure, 'push');
            app.DeleteSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubjectButtonPushed, true);
            app.DeleteSubjectButton.Text = 'Delete the mouse record';
            app.bornbeforeDatePickerLabel = uilabel(app.UIFigure);
            app.bornbeforeDatePickerLabel.Text = 'born before';
            app.bornbeforeFilterDatePicker = uidatepicker(app.UIFigure);
            app.bornbeforeFilterDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.bornbeforeFilterDatePicker.ValueChangedFcn = createCallbackFcn(app, @bornbeforeFilterDatePickerValueChanged, true);
            app.bornafterDatePickerLabel = uilabel(app.UIFigure);
            app.bornafterDatePickerLabel.Text = 'born after';
            app.bornafterFilterDatePicker = uidatepicker(app.UIFigure);
            app.bornafterFilterDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.bornafterFilterDatePicker.ValueChangedFcn = createCallbackFcn(app, @bornafterFilterDatePickerValueChanged, true);
            app.strainDropDownLabel = uilabel(app.UIFigure);
            app.strainDropDownLabel.Text = 'strain';
            app.strainFilterDropDown = uidropdown(app.UIFigure);
            app.strainFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @strainFilterDropDownValueChanged, true);
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.OtherMessageTextArea = uitextarea(app.UIFigure);
            app.SubjectTable = uitable(app.UIFigure);
            app.SubjectTable.ColumnName = {'subject_id'; 'nickname'; 'dob'; 'sex'};
            app.SubjectTable.RowName = {};
            app.SubjectTable.ColumnSortable = true;
            app.SubjectTable.CellSelectionCallback = createCallbackFcn(app, @SubjectTableCellSelection, true);
            app.ZygosityTable = uitable(app.UIFigure);
            app.ZygosityTable.ColumnName = {'subject_id'; 'allele_name'; 'zygosity'; 'zygosity_ts'};
            app.ZygosityTable.RowName = {};
            app.AddSubjectButton = uibutton(app.UIFigure, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Text = 'Insert a new mouse record';
            app.AlleleTable = uitable(app.UIFigure);
            app.AlleleTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AlleleTable.RowName = {};
            app.AlleleLabel = uilabel(app.UIFigure);
            app.AlleleLabel.Text = 'Allele';
            app.SourceTable = uitable(app.UIFigure);
            app.SourceTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SourceTable.RowName = {};
            app.SourceTable.ColumnSortable = true;
            app.SourceLabel = uilabel(app.UIFigure);
            app.SourceLabel.Text = 'Source';
            app.AddSourceButton = uibutton(app.UIFigure, 'push');
            app.AddSourceButton.ButtonPushedFcn = createCallbackFcn(app, @AddSourceButtonPushed, true);
            app.AddSourceButton.Text = 'Add';
            app.DeleteSourceButton = uibutton(app.UIFigure, 'push');
            app.DeleteSourceButton.Text = 'Delete';
            app.UpdateSourceButton = uibutton(app.UIFigure, 'push');
            app.UpdateSourceButton.Text = 'Update';
            app.AddStrainButton = uibutton(app.UIFigure, 'push');
            app.AddStrainButton.ButtonPushedFcn = createCallbackFcn(app, @AddStrainButtonPushed, true);
            app.AddStrainButton.Text = 'Add';
            app.DeleteStrainButton = uibutton(app.UIFigure, 'push');
            app.DeleteStrainButton.Text = 'Delete';
            app.UpdateStrainButton = uibutton(app.UIFigure, 'push');
            app.UpdateStrainButton.Text = 'Update';
            app.AddAlleleButton = uibutton(app.UIFigure, 'push');
            app.AddAlleleButton.ButtonPushedFcn = createCallbackFcn(app, @AddAlleleButtonPushed, true);
            app.AddAlleleButton.Text = 'Add';
            app.DeleteAlleleButton = uibutton(app.UIFigure, 'push');
            app.DeleteAlleleButton.Text = 'Delete';
            app.UpdateAlleleButton = uibutton(app.UIFigure, 'push');
            app.UpdateAlleleButton.Text = 'Update';
            app.DeathTable = uitable(app.UIFigure);
            app.DeathTable.ColumnName = {'subject_id'; 'dod'; 'reason_of_death'; 'death_notes'; 'death_ts'};
            app.DeathTable.RowName = {};
            app.ZygosityLabel = uilabel(app.UIFigure);
            app.ZygosityLabel.Text = 'Zygosity';
            app.DeathLabel = uilabel(app.UIFigure);
            app.DeathLabel.Text = 'Death';
            app.NicknameTable = uitable(app.UIFigure);
            app.NicknameTable.ColumnName = {'nickname'; 'subject_id'};
            app.NicknameTable.RowName = {};
            app.NicknameLabel = uilabel(app.UIFigure);
            app.NicknameLabel.Text = 'Nickname';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'strain_name'; 'allele_name'};
            app.UITable.RowName = {};
            app.StrainAlleleLabel = uilabel(app.UIFigure);
            app.StrainAlleleLabel.Text = 'StrainAllele';
            app.ClearselectionButton = uibutton(app.UIFigure, 'push');
            app.ClearselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectionButtonPushed, true);
            app.ClearselectionButton.Text = 'Clear selection';
            app.subject_idEditFieldLabel = uilabel(app.UIFigure);
            app.subject_idEditFieldLabel.Text = 'subject_id';
            app.subject_idEditField = uieditfield(app.UIFigure, 'text');
            app.subject_idEditField.ValueChangedFcn = createCallbackFcn(app, @subject_idEditFieldValueChanged, true);
            app.nicknameEditFieldLabel = uilabel(app.UIFigure);
            app.nicknameEditFieldLabel.Text = 'nickname';
            app.nicknameEditField = uieditfield(app.UIFigure, 'text');
            app.nicknameEditField.ValueChangedFcn = createCallbackFcn(app, @nicknameEditFieldValueChanged, true);
            app.SubjectLabel_2 = uilabel(app.UIFigure);
            app.SubjectLabel_2.Text = 'Subject';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MouseGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end