classdef AddSubjectDialogue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NewSubjectLabel                matlab.ui.control.Label
        MainSubjectTableFormPanel      matlab.ui.container.Panel
        subject_idLabel_2              matlab.ui.control.Label
        subject_id                     matlab.ui.control.EditField
        dobLabel_2                     matlab.ui.control.Label
        dob                            matlab.ui.control.DatePicker
        subject_tsLabel_2              matlab.ui.control.Label
        subject_ts                     matlab.ui.control.EditField
        sexLabel_2                     matlab.ui.control.Label
        sex                            matlab.ui.control.DropDown
        mark_typeLabel_2               matlab.ui.control.Label
        mark_type                      matlab.ui.control.DropDown
        markEditFieldLabel_2           matlab.ui.control.Label
        mark                           matlab.ui.control.EditField
        strain_nameLabel_2             matlab.ui.control.Label
        strain_name                    matlab.ui.control.DropDown
        source_nameLabel_2             matlab.ui.control.Label
        source_name                    matlab.ui.control.DropDown
        breeding_pairEditFieldLabel_2  matlab.ui.control.Label
        breeding_pair                  matlab.ui.control.EditField
        subject_notesEditFieldLabel_2  matlab.ui.control.Label
        subject_notes                  matlab.ui.control.EditField
        AddamouseButton                matlab.ui.control.Button
        InsertrecordButton             matlab.ui.control.Button
        ClearformsButton               matlab.ui.control.Button
        MessageTextAreaLabel           matlab.ui.control.Label
        MessageTextArea                matlab.ui.control.TextArea
        UITable                        matlab.ui.control.Table
        ZygosityLabel                  matlab.ui.control.Label
        DeathPanel                     matlab.ui.container.Panel
        dodLabel                       matlab.ui.control.Label
        dod                            matlab.ui.control.DatePicker
        reason_of_deathDropDownLabel   matlab.ui.control.Label
        reason_of_death                matlab.ui.control.DropDown
        death_tsEditFieldLabel         matlab.ui.control.Label
        death_ts                       matlab.ui.control.EditField
        death_notesEditFieldLabel      matlab.ui.control.Label
        death_notes                    matlab.ui.control.EditField
        NicknamePanel                  matlab.ui.container.Panel
        nicknameLabel                  matlab.ui.control.Label
        nickname                       matlab.ui.control.EditField
        OptionaltablesLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        conn
        CallingApp
        currentSubjectForm % currentSubjectForm
        currentNicknameForm
        currentDeathForm
        currentZygosityData
        subjectColumnNames
        nicknameColumnNames
        deathColumnNames
        formElements % mapping of column names and GUI elements
    end
    
    methods (Access = private)
       
        function createFormElements(app, tableName)
           
            switch tableName 
                case 'subject'
                    table = subject.Subject;
                case 'nickname'
                    table = subject.Nickname;
                case 'death'
                    table = subject.Death; 
            end
            
            columnNames = app.([tableName 'ColumnNames']);
            
            for iColumn = 1:length(columnNames)
                column = columnNames{iColumn};
               
                if isprop(app, column)
                    if isempty(app.formElements)
                        app.formElements(1).name = column;
                        app.formElements(1).element = app.(column);
                        app.formElements(1).table = table;
                    else
                        elementNames = {app.formElements.name};
                        % skip any columns that already in the elements
                        if ~any(cellfun(@isequal, elementNames, repmat({column}, size(elementNames))))
                            app.formElements(end+1) = struct(...
                                'name', column, ...
                                'element', app.(column), ...
                                'table', table);
                        end
                    end
                end
            end
            
        end
        
        function initializeForms(app)
            
            for field = app.formElements                
                ele = field.element;
                tableName = extractAfter(field.table.className, '.');
                
                % get options if the current element is a dropdown list
                if isa(ele, 'matlab.ui.control.DropDown')
                    ele.Items = utils.getOptions(field.table, field.name, app.conn);
                    ele.Items(end+1) = {''};
                    app.(['current' tableName 'Form']).(field.name) = '';
                    ele.Value = '';
                end

                % get default values and insert data into the corresponding
                % GUI elements
                default = utils.getDefaultValue(field.table, field.name);
                if ~isempty(default)
                    ele.Value = default;
                    app.(['current' tableName 'Form']).(field.name) = default;
                elseif contains(field.name, '_ts')
                    timeNow = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                    ele.Value = timeNow;
                    app.(['current' tableName 'Form']).(field.name) = timeNow;
                end
                
            end
        end
        
        function initializeZygosityTable(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
             % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.conn = mainapp.conn;
            app.subjectColumnNames = mainapp.subjectColumnNames;
            app.nicknameColumnNames = mainapp.nicknameColumnNames;
            app.deathColumnNames = mainapp.deathColumnNames;
            for table = {'subject', 'nickname', 'death'}
                app.createFormElements(table{1})
            end
            
            app.initializeForms();
        end

        % Button pushed function: InsertrecordButton
        function InsertrecordButtonPushed(app, event)
            for field = fields(app.currentSubjectForm)'
                f = field{1};
                if isempty(app.currentSubjectForm.(f))
                    app.currentSubjectForm = rmfield(app.currentSubjectForm, f);
                end
                if strcmp(f, 'dob')
                    app.currentSubjectForm.(f) = datestr(app.currentSubjectForm.(f), 'yyyy-mm-dd');
                end
            end
            subj_entry = app.currentSubjectForm;
            
            try
                insert(subject.Subject, subj_entry)
                app.MessageTextArea.Value = 'Successfully inserted Subject record!';
            catch exception                
                app.MessageTextArea.Value = sprintf('Error when inserting the Subject entry: %s', exception.message);
            end
            
            % if nickname form is not empty
%             if ~isempty(app.currentNicknameForm.nickname)
%                  
%             end
%            
            app.CallingApp.SubjectTable.Data = struct2cell(fetch(subject.Subject, '*'))';
        end

        % Value changed function: subject_id
        function subject_idValueChanged(app, event)
            value = app.subject_id.Value;
            app.currentSubjectForm.subject_id = value;
        end

        % Callback function
        function subject_colony_nameValueChanged(app, event)
            value = app.subject_colony_name.Value;
            app.currentSubjectForm.subject_colony_name = value;
        end

        % Callback function
        function nicknameValueChanged(app, event)
            value = app.nickname.Value;
            app.currentSubjectForm.nickname = value;
        end

        % Value changed function: dob
        function dobValueChanged(app, event)
            value = app.dob.Value;
            app.currentSubjectForm.dob = value;
        end

        % Value changed function: sex
        function sexValueChanged(app, event)
            value = app.sex.Value;
            app.currentSubjectForm.sex = value;
        end

        % Value changed function: strain_name
        function strain_nameValueChanged(app, event)
            value = app.strain_name.Value;
            app.currentSubjectForm.strain_name = value;
        end

        % Value changed function: mark_type
        function mark_typeValueChanged(app, event)
            value = app.mark_type.Value;
            app.currentSubjectForm.mark_type = value;
        end

        % Value changed function: mark
        function markValueChanged(app, event)
            value = app.mark.Value;
            app.currentSubjectForm.mark = value;
        end

        % Value changed function: source_name
        function source_nameValueChanged(app, event)
            value = app.source_name.Value;
            app.currentSubjectForm.source_name = value;
        end

        % Value changed function: breeding_pair
        function breeding_pairValueChanged(app, event)
            value = app.breeding_pair.Value;
            app.currentSubjectForm.breeding_pair = value;
        end

        % Value changed function: subject_ts
        function subject_tsValueChanged(app, event)
            value = app.subject_ts.Value;
            app.currentSubjectForm.subject_ts = value;
        end

        % Value changed function: subject_notes
        function subject_notesValueChanged(app, event)
            value = app.subject_notes.Value;
            app.currentSubjectForm.subject_notes = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.AddSubjectButton.Enable = 'on';
            delete(app)      
        end

        % Value changed function: nickname
        function nicknameValueChanged2(app, event)
            value = app.nickname.Value;
            app.currentNicknameForm.nickname = value;
        end

        % Value changed function: dod
        function dodValueChanged(app, event)
            value = app.dod.Value;
            app.currentDeathForm.dod = value;
        end

        % Value changed function: reason_of_death
        function reason_of_deathValueChanged(app, event)
            value = app.reason_of_death.Value;
            app.currentDeathForm.reason_of_death = value;
        end

        % Value changed function: death_notes
        function death_notesValueChanged(app, event)
            value = app.death_notes.Value;
            app.currentDeathForm.death_notes = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NewSubjectLabel = uilabel(app.UIFigure);
            app.NewSubjectLabel.Text = 'New Subject';
            app.MainSubjectTableFormPanel = uipanel(app.UIFigure);
            app.MainSubjectTableFormPanel.Title = 'Main Subject Table Form*';
            app.subject_idLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.subject_idLabel_2.Text = 'subject_id*';
            app.subject_id = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.subject_id.ValueChangedFcn = createCallbackFcn(app, @subject_idValueChanged, true);
            app.dobLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.dobLabel_2.Text = 'dob*';
            app.dob = uidatepicker(app.MainSubjectTableFormPanel);
            app.dob.DisplayFormat = 'uuuu-MM-dd';
            app.dob.ValueChangedFcn = createCallbackFcn(app, @dobValueChanged, true);
            app.subject_tsLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.subject_tsLabel_2.Text = 'subject_ts';
            app.subject_ts = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.subject_ts.ValueChangedFcn = createCallbackFcn(app, @subject_tsValueChanged, true);
            app.sexLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.sexLabel_2.Text = 'sex*';
            app.sex = uidropdown(app.MainSubjectTableFormPanel);
            app.sex.ValueChangedFcn = createCallbackFcn(app, @sexValueChanged, true);
            app.mark_typeLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.mark_typeLabel_2.Text = 'mark_type*';
            app.mark_type = uidropdown(app.MainSubjectTableFormPanel);
            app.mark_type.ValueChangedFcn = createCallbackFcn(app, @mark_typeValueChanged, true);
            app.markEditFieldLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.markEditFieldLabel_2.Text = 'mark';
            app.mark = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.mark.ValueChangedFcn = createCallbackFcn(app, @markValueChanged, true);
            app.strain_nameLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.strain_nameLabel_2.Text = 'strain_name*';
            app.strain_name = uidropdown(app.MainSubjectTableFormPanel);
            app.strain_name.ValueChangedFcn = createCallbackFcn(app, @strain_nameValueChanged, true);
            app.source_nameLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.source_nameLabel_2.Text = 'source_name*';
            app.source_name = uidropdown(app.MainSubjectTableFormPanel);
            app.source_name.ValueChangedFcn = createCallbackFcn(app, @source_nameValueChanged, true);
            app.breeding_pairEditFieldLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.breeding_pairEditFieldLabel_2.Text = 'breeding_pair';
            app.breeding_pair = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.breeding_pair.ValueChangedFcn = createCallbackFcn(app, @breeding_pairValueChanged, true);
            app.subject_notesEditFieldLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.subject_notesEditFieldLabel_2.Text = 'subject_notes';
            app.subject_notes = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.subject_notes.ValueChangedFcn = createCallbackFcn(app, @subject_notesValueChanged, true);
            app.AddamouseButton = uibutton(app.UIFigure, 'push');
            app.AddamouseButton.Text = 'Add a mouse';
            app.InsertrecordButton = uibutton(app.UIFigure, 'push');
            app.InsertrecordButton.ButtonPushedFcn = createCallbackFcn(app, @InsertrecordButtonPushed, true);
            app.InsertrecordButton.Text = 'Insert record';
            app.ClearformsButton = uibutton(app.UIFigure, 'push');
            app.ClearformsButton.Text = 'Clear forms';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'allele_name'; 'zygosity'; 'zygosity_ts'};
            app.UITable.RowName = {};
            app.ZygosityLabel = uilabel(app.UIFigure);
            app.ZygosityLabel.Text = 'Zygosity';
            app.DeathPanel = uipanel(app.UIFigure);
            app.DeathPanel.Title = 'Death';
            app.dodLabel = uilabel(app.DeathPanel);
            app.dodLabel.Text = 'dod*';
            app.dod = uidatepicker(app.DeathPanel);
            app.dod.DisplayFormat = 'uuuu-MM-dd';
            app.dod.ValueChangedFcn = createCallbackFcn(app, @dodValueChanged, true);
            app.reason_of_deathDropDownLabel = uilabel(app.DeathPanel);
            app.reason_of_deathDropDownLabel.Text = 'reason_of_death*';
            app.reason_of_death = uidropdown(app.DeathPanel);
            app.reason_of_death.ValueChangedFcn = createCallbackFcn(app, @reason_of_deathValueChanged, true);
            app.death_tsEditFieldLabel = uilabel(app.DeathPanel);
            app.death_tsEditFieldLabel.Text = 'death_ts';
            app.death_ts = uieditfield(app.DeathPanel, 'text');
            app.death_notesEditFieldLabel = uilabel(app.DeathPanel);
            app.death_notesEditFieldLabel.Text = 'death_notes';
            app.death_notes = uieditfield(app.DeathPanel, 'text');
            app.death_notes.ValueChangedFcn = createCallbackFcn(app, @death_notesValueChanged, true);
            app.NicknamePanel = uipanel(app.UIFigure);
            app.NicknamePanel.Title = 'Nickname';
            app.nicknameLabel = uilabel(app.NicknamePanel);
            app.nicknameLabel.Text = 'nickname*';
            app.nickname = uieditfield(app.NicknamePanel, 'text');
            app.nickname.ValueChangedFcn = createCallbackFcn(app, @nicknameValueChanged2, true);
            app.OptionaltablesLabel = uilabel(app.UIFigure);
            app.OptionaltablesLabel.Text = 'Optional tables';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddSubjectDialogue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end