classdef MouseGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StrainTable                  matlab.ui.control.Table
        StrainLabel                  matlab.ui.control.Label
        SubjectLabel                 matlab.ui.control.Label
        UpdateSubjectButton          matlab.ui.control.Button
        SubmitfilterButton           matlab.ui.control.Button
        ResetfilterButton            matlab.ui.control.Button
        DeleteSubjectButton          matlab.ui.control.Button
        subject_idDropDownLabel      matlab.ui.control.Label
        subject_idFilterDropDown     matlab.ui.control.DropDown
        FlexiblequeryEditFieldLabel  matlab.ui.control.Label
        FlexiblequeryEditField       matlab.ui.control.EditField
        nicknameDropDownLabel        matlab.ui.control.Label
        nicknameFilterDropDown       matlab.ui.control.DropDown
        bornbeforeDatePickerLabel    matlab.ui.control.Label
        bornbeforeFilterDatePicker   matlab.ui.control.DatePicker
        bornafterDatePickerLabel     matlab.ui.control.Label
        bornafterFilterDatePicker    matlab.ui.control.DatePicker
        strainDropDownLabel          matlab.ui.control.Label
        strainFilterDropDown         matlab.ui.control.DropDown
        MessageTextAreaLabel         matlab.ui.control.Label
        OtherMessageTextArea         matlab.ui.control.TextArea
        SubjectTable                 matlab.ui.control.Table
        ZygosityTable                matlab.ui.control.Table
        AddSubjectButton             matlab.ui.control.Button
        AlleleTable                  matlab.ui.control.Table
        AlleleLabel                  matlab.ui.control.Label
        SourceTable                  matlab.ui.control.Table
        SourceLabel                  matlab.ui.control.Label
        AddSourceButton              matlab.ui.control.Button
        DeleteSourceButton           matlab.ui.control.Button
        UpdateSourceButton           matlab.ui.control.Button
        MessageTextArea_2Label       matlab.ui.control.Label
        SubjectMessageTextArea       matlab.ui.control.TextArea
        AddStrainButton              matlab.ui.control.Button
        DeleteStrainButton           matlab.ui.control.Button
        UpdateStrainButton           matlab.ui.control.Button
        AddAlleleButton              matlab.ui.control.Button
        DeleteAlleleButton           matlab.ui.control.Button
        UpdateAlleleButton           matlab.ui.control.Button
        DeathTable                   matlab.ui.control.Table
        ZygosityLabel                matlab.ui.control.Label
        DeathLabel                   matlab.ui.control.Label
        NicknameTable                matlab.ui.control.Table
        NicknameLabel                matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        StrainAlleleLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
      
        % other apps to be called
        AddSourceApp
        UpdateSourceApp
        AddStrainApp    % Add strain window
        UpdateStrainApp
        AddAlleleApp
        UpdateAlleleApp
        AddSubjectApp
        UpdateSubjectApp
        
    end
    
    properties (Access = public)
        
        conn
         % current data shown in the form
        sourceData      % current data for table lab.Source
        strainData % current data for table subject.Strain
        alleleData 
        subjectData
        nicknameData
        zygosityData
        deathData
        
        % tables used
        sourceColumnNames
        strainColumnNames
        alleleColumnNames
        subjectColumnNames
        nicknameColumnNames
        zygosityColumnNames
        deathColumnNames
    end
    
    methods (Access = private)
        
        function loadTable(app, table, sub)
            
            if nargin < 3
                sub = false;
            end
            tableName = extractAfter(class(table), '.');
            dataName = [lower(tableName) 'Data'];
            listTableName = [tableName 'Table'];
            currentData = fetch(table, '*');
            app.(dataName) = currentData;
            columnNames = fieldnames(currentData);
            app.(listTableName).ColumnName = columnNames;
            app.([lower(tableName) 'ColumnNames']) = columnNames;
            
            
            if ~isempty(currentData)
                app.(listTableName).Data = struct2cell(currentData)';
            end
            
            if ~sub
                app.(['Update' tableName 'Button']).Enable = 'off';
                app.(['Delete' tableName 'Button']).Enable = 'off';
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateStrainTable(app)
            app.loadTable(subject.Strain)
        end
        function updateSourceTable(app)
            app.loadTable(lab.Source)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.conn = dj.conn('127.0.0.1', 'shan', 'shan');
            
            app.loadTable(subject.Subject)
            app.loadTable(lab.Source)
            app.loadTable(subject.Strain)
            app.loadTable(subject.Allele)
            app.loadTable(subject.Nickname, true)
            app.loadTable(subject.Death, true)
        end

        % Button pushed function: AddStrainButton
        function AddStrainButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AddStrainButton.Enable = 'off';
            % Open the options dialog and pass inputs
            app.AddStrainApp = AddStrainDialogue(app);
        end

        % Button pushed function: AddSourceButton
        function AddSourceButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AddSourceButton.Enable = 'off';
            % Open the options dialog and pass inputs
            app.AddSourceApp = AddSourceDialogue(app);   
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AddSubjectButton.Enable = 'off';
            % Open the options dialog and pass inputs
            app.AddSubjectApp = AddSubjectDialogue(app);
        end

        % Button pushed function: AddAlleleButton
        function AddAlleleButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AddAlleleButton.Enable = 'off';
            % Open the options dialog and pass inputs
            app.AddAlleleApp = AddAlleleDialogue(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StrainTable = uitable(app.UIFigure);
            app.StrainTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StrainTable.RowName = {};
            app.StrainLabel = uilabel(app.UIFigure);
            app.StrainLabel.Text = 'Strain';
            app.SubjectLabel = uilabel(app.UIFigure);
            app.SubjectLabel.Text = 'Subject';
            app.UpdateSubjectButton = uibutton(app.UIFigure, 'push');
            app.UpdateSubjectButton.Text = 'Update the mouse record';
            app.SubmitfilterButton = uibutton(app.UIFigure, 'push');
            app.SubmitfilterButton.Text = 'Submit filter';
            app.ResetfilterButton = uibutton(app.UIFigure, 'push');
            app.ResetfilterButton.Text = 'Reset filter';
            app.DeleteSubjectButton = uibutton(app.UIFigure, 'push');
            app.DeleteSubjectButton.Text = 'Delete the mouse record';
            app.subject_idDropDownLabel = uilabel(app.UIFigure);
            app.subject_idDropDownLabel.Text = 'subject_id';
            app.subject_idFilterDropDown = uidropdown(app.UIFigure);
            app.FlexiblequeryEditFieldLabel = uilabel(app.UIFigure);
            app.FlexiblequeryEditFieldLabel.Text = 'Flexible query ';
            app.FlexiblequeryEditField = uieditfield(app.UIFigure, 'text');
            app.nicknameDropDownLabel = uilabel(app.UIFigure);
            app.nicknameDropDownLabel.Text = 'nickname';
            app.nicknameFilterDropDown = uidropdown(app.UIFigure);
            app.bornbeforeDatePickerLabel = uilabel(app.UIFigure);
            app.bornbeforeDatePickerLabel.Text = 'born before';
            app.bornbeforeFilterDatePicker = uidatepicker(app.UIFigure);
            app.bornafterDatePickerLabel = uilabel(app.UIFigure);
            app.bornafterDatePickerLabel.Text = 'born after';
            app.bornafterFilterDatePicker = uidatepicker(app.UIFigure);
            app.strainDropDownLabel = uilabel(app.UIFigure);
            app.strainDropDownLabel.Text = 'strain';
            app.strainFilterDropDown = uidropdown(app.UIFigure);
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.OtherMessageTextArea = uitextarea(app.UIFigure);
            app.SubjectTable = uitable(app.UIFigure);
            app.SubjectTable.ColumnName = {'subject_id'; 'nickname'; 'dob'; 'sex'};
            app.SubjectTable.RowName = {};
            app.ZygosityTable = uitable(app.UIFigure);
            app.ZygosityTable.ColumnName = {'allele_name'; 'zygosity'; 'zygosity_ts'};
            app.ZygosityTable.RowName = {};
            app.AddSubjectButton = uibutton(app.UIFigure, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Text = 'Insert a new mouse record';
            app.AlleleTable = uitable(app.UIFigure);
            app.AlleleTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AlleleTable.RowName = {};
            app.AlleleLabel = uilabel(app.UIFigure);
            app.AlleleLabel.Text = 'Allele';
            app.SourceTable = uitable(app.UIFigure);
            app.SourceTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SourceTable.RowName = {};
            app.SourceLabel = uilabel(app.UIFigure);
            app.SourceLabel.Text = 'Source';
            app.AddSourceButton = uibutton(app.UIFigure, 'push');
            app.AddSourceButton.ButtonPushedFcn = createCallbackFcn(app, @AddSourceButtonPushed, true);
            app.AddSourceButton.Text = 'Add';
            app.DeleteSourceButton = uibutton(app.UIFigure, 'push');
            app.DeleteSourceButton.Text = 'Delete';
            app.UpdateSourceButton = uibutton(app.UIFigure, 'push');
            app.UpdateSourceButton.Text = 'Update';
            app.MessageTextArea_2Label = uilabel(app.UIFigure);
            app.MessageTextArea_2Label.Text = 'Message';
            app.SubjectMessageTextArea = uitextarea(app.UIFigure);
            app.AddStrainButton = uibutton(app.UIFigure, 'push');
            app.AddStrainButton.ButtonPushedFcn = createCallbackFcn(app, @AddStrainButtonPushed, true);
            app.AddStrainButton.Text = 'Add';
            app.DeleteStrainButton = uibutton(app.UIFigure, 'push');
            app.DeleteStrainButton.Text = 'Delete';
            app.UpdateStrainButton = uibutton(app.UIFigure, 'push');
            app.UpdateStrainButton.Text = 'Update';
            app.AddAlleleButton = uibutton(app.UIFigure, 'push');
            app.AddAlleleButton.ButtonPushedFcn = createCallbackFcn(app, @AddAlleleButtonPushed, true);
            app.AddAlleleButton.Text = 'Add';
            app.DeleteAlleleButton = uibutton(app.UIFigure, 'push');
            app.DeleteAlleleButton.Text = 'Delete';
            app.UpdateAlleleButton = uibutton(app.UIFigure, 'push');
            app.UpdateAlleleButton.Text = 'Update';
            app.DeathTable = uitable(app.UIFigure);
            app.DeathTable.ColumnName = {'dod'; 'reason_of_death'; 'death_notes'; 'death_ts'};
            app.DeathTable.RowName = {};
            app.ZygosityLabel = uilabel(app.UIFigure);
            app.ZygosityLabel.Text = 'Zygosity';
            app.DeathLabel = uilabel(app.UIFigure);
            app.DeathLabel.Text = 'Death';
            app.NicknameTable = uitable(app.UIFigure);
            app.NicknameTable.ColumnName = {'nickname'};
            app.NicknameTable.RowName = {};
            app.NicknameLabel = uilabel(app.UIFigure);
            app.NicknameLabel.Text = 'Nickname';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.StrainAlleleLabel = uilabel(app.UIFigure);
            app.StrainAlleleLabel.Text = 'StrainAllele';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MouseGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end